classdef projekt < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BinaryzacjaMarekKietykaMichaLeszczyskiUIFigure  matlab.ui.Figure
        WczytajobrazButton           matlab.ui.control.Button
        BinaryzacjametodOtsuButton   matlab.ui.control.Button
        BinaryzacjazprogiemadaptiveButton  matlab.ui.control.Button
        BinaryzacjazprogiemwasnymButton  matlab.ui.control.Button
        PrgwasnyEditFieldLabel       matlab.ui.control.Label
        PrgwasnyEditField            matlab.ui.control.NumericEditField
        Slider                       matlab.ui.control.Slider
        UIAxes                       matlab.ui.control.UIAxes
        UIAxes2                      matlab.ui.control.UIAxes
        MiejscenawczytanyobrazLabel  matlab.ui.control.Label
        MiejscenazbinaryzowanyobrazLabel  matlab.ui.control.Label
    end


    properties (Access = public)
        OriginalImage   double
        BinarizedImage  double
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            set(app.UIAxes, 'visible', 'off');
            set(app.UIAxes2, 'visible', 'off');
            set(app.UIAxes, 'xtick', []);
            set(app.UIAxes, 'ytick', []);
            set(app.UIAxes2, 'xtick', []);
            set(app.UIAxes2, 'ytick', []);
        end

        % Button pushed function: BinaryzacjazprogiemadaptiveButton
        function binarizeWithAdaptiveThreshold(app, event)
            % TODO - implementacja algorytmu 'adaptive'
        end

        % Button pushed function: BinaryzacjametodOtsuButton
        function binarizeWithOtsuThreshold(app, event)
            % TODO - implementacja algorytmu metoda Otsu
            % TODO - OBRAZ RENDERUJE SIĘ PO KLIKNIĘCIU INNEGO BUTTONA - DO POPRAWY
            app.BinarizedImage = imbinarize(app.OriginalImage, 'adaptive');
            app.MiejscenazbinaryzowanyobrazLabel.Text = {''; ''};
            imshow(app.BinarizedImage, 'parent', app.UIAxes2);
        end

        % Button pushed function: BinaryzacjazprogiemwasnymButton
        function binarizeWithOwnThreshold(app, event)
            % TODO - implementacja algorytmu z wlasnym progiem
        end

        % Button pushed function: WczytajobrazButton
        function loadImage(app, event)
            pause on;
            [file, path] = uigetfile('*.jpg; *.png; *.jpeg; *.PNG; *.JPEG; *.jpeg', 'Wybierz obraz do zbinaryzowania');
            
            if ~isequal(file, 0)
               pathToFile = fullfile(path, file);
               app.OriginalImage = rgb2gray(double(imread(pathToFile)) / 255);
               app.MiejscenawczytanyobrazLabel.Text = {''; ''};
               cla(app.UIAxes2);
               pause(0.5);
               app.MiejscenazbinaryzowanyobrazLabel.Text = {'Miejsce na'; 'zbinaryzowany obraz'};
               imshow(app.OriginalImage, 'parent', app.UIAxes);
            end
        end

        % Callback function
        function updateNumericField(app, event)
            app.PrgwasnyEditField.Value = app.Slider.Value;
        end

        % Value changed function: PrgwasnyEditField
        function updateSliderValue(app, event)
            app.Slider.Value = app.PrgwasnyEditField.Value;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BinaryzacjaMarekKietykaMichaLeszczyskiUIFigure = uifigure;
            app.BinaryzacjaMarekKietykaMichaLeszczyskiUIFigure.Name = 'Binaryzacja - Marek Kiełtyka, Michał Leszczyński';
            app.WczytajobrazButton = uibutton(app.BinaryzacjaMarekKietykaMichaLeszczyskiUIFigure, 'push');
            app.WczytajobrazButton.ButtonPushedFcn = createCallbackFcn(app, @loadImage, true);
            app.WczytajobrazButton.Text = 'Wczytaj obraz';
            app.BinaryzacjametodOtsuButton = uibutton(app.BinaryzacjaMarekKietykaMichaLeszczyskiUIFigure, 'push');
            app.BinaryzacjametodOtsuButton.ButtonPushedFcn = createCallbackFcn(app, @binarizeWithOtsuThreshold, true);
            app.BinaryzacjametodOtsuButton.Text = {'Binaryzacja'; 'metodą Otsu'};
            app.BinaryzacjazprogiemadaptiveButton = uibutton(app.BinaryzacjaMarekKietykaMichaLeszczyskiUIFigure, 'push');
            app.BinaryzacjazprogiemadaptiveButton.ButtonPushedFcn = createCallbackFcn(app, @binarizeWithAdaptiveThreshold, true);
            app.BinaryzacjazprogiemadaptiveButton.Text = {'Binaryzacja'; 'z progiem adaptive'};
            app.BinaryzacjazprogiemwasnymButton = uibutton(app.BinaryzacjaMarekKietykaMichaLeszczyskiUIFigure, 'push');
            app.BinaryzacjazprogiemwasnymButton.ButtonPushedFcn = createCallbackFcn(app, @binarizeWithOwnThreshold, true);
            app.BinaryzacjazprogiemwasnymButton.Text = {'Binaryzacja'; 'z progiem własnym'};
            app.PrgwasnyEditFieldLabel = uilabel(app.BinaryzacjaMarekKietykaMichaLeszczyskiUIFigure);
            app.PrgwasnyEditFieldLabel.Text = 'Próg własny';
            app.PrgwasnyEditField = uieditfield(app.BinaryzacjaMarekKietykaMichaLeszczyskiUIFigure, 'numeric');
            app.PrgwasnyEditField.ValueChangedFcn = createCallbackFcn(app, @updateSliderValue, true);
            app.PrgwasnyEditField.Limits = [0 1];
            app.PrgwasnyEditField.ValueDisplayFormat = '%.3f';
            app.Slider = uislider(app.BinaryzacjaMarekKietykaMichaLeszczyskiUIFigure);
            app.Slider.Limits = [0 1];
            app.Slider.MajorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @updateNumericField, true);
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @updateNumericField, true);
            app.UIAxes = uiaxes(app.BinaryzacjaMarekKietykaMichaLeszczyskiUIFigure);
            app.UIAxes2 = uiaxes(app.BinaryzacjaMarekKietykaMichaLeszczyskiUIFigure);
            app.MiejscenawczytanyobrazLabel = uilabel(app.BinaryzacjaMarekKietykaMichaLeszczyskiUIFigure);
            app.MiejscenawczytanyobrazLabel.Text = {'Miejsce na'; 'wczytany obraz'};
            app.MiejscenazbinaryzowanyobrazLabel = uilabel(app.BinaryzacjaMarekKietykaMichaLeszczyskiUIFigure);
            app.MiejscenazbinaryzowanyobrazLabel.Text = {'Miejsce na'; 'zbinaryzowany obraz'};
        end
    end

    methods (Access = public)

        % Construct app
        function app = projekt

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BinaryzacjaMarekKietykaMichaLeszczyskiUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BinaryzacjaMarekKietykaMichaLeszczyskiUIFigure)
        end
    end
end